classdef SeaUrchinApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        TabGroup                   matlab.ui.container.TabGroup
        MeasureTab                 matlab.ui.container.Tab
        SelectEggFolderButton      matlab.ui.control.Button
        SelectControlFolderButton  matlab.ui.control.Button
        SelectSampleFolderButton   matlab.ui.control.Button
        MeasureButton              matlab.ui.control.Button
        UITable                    matlab.ui.control.Table
        UIAxes                     matlab.ui.control.UIAxes
        Image                      matlab.ui.control.Image
        ByIkerAlvarezMoraLabel     matlab.ui.control.Label
        Image2                     matlab.ui.control.Image
        ClassificationTab          matlab.ui.container.Tab
        STARTButton                matlab.ui.control.StateButton
        UIAxes2                    matlab.ui.control.UIAxes
        Image_2                    matlab.ui.control.Image
        ByIkerAlvarezMoraLabel_2   matlab.ui.control.Label
        Image2_2                   matlab.ui.control.Image
        UITable2                   matlab.ui.control.Table
    end

    
    properties (Access = public)
         
       folderEgg % Description
       folderControl
       folderSample
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectEggFolderButton
        function SelectEggFolderButtonPushed(app, event)
            app.folderEgg = uigetdir(matlabroot,'Select Folder');
        end

        % Button pushed function: SelectControlFolderButton
        function SelectControlFolderButtonPushed(app, event)
             app.folderControl = uigetdir(matlabroot,'Select Folder');
        end

        % Button pushed function: SelectSampleFolderButton
        function SelectSampleFolderButtonPushed(app, event)
             app.folderSample = uigetdir(matlabroot,'Select Folder');
        end

        % Button pushed function: MeasureButton
        function MeasureButtonPushed(app, event)
        waitbar(10,'Please wait...');   
        
            cd (app.folderEgg)
            names = EggSep();
            files2 = dir('*tiff'); 

        for i = 1:length(files2)
              a = files2(i).name;
              b{i} = a
         end
         b = b'
         c = setdiff(b,names)

        for i = 1:length(files2)/2
             delete(char(c(i)));
        end
            
            
            cd (app.folderControl)
            names = SampleSep()
            files = dir('*.tif');
            for k = 1:length(files)
                try
                    [subsamples] = UrchinFinder(files(k).name,1);
                catch
                end
                close all
                files2 = dir('*tiff');
            end
            for i = 1:length(files2)
                a = files2(i).name;
                b{i} = a
            end
            b = b'
            c = setdiff(b,names)
            
            for i = 1:length(files2)/2
                delete(char(c(i)));
            end
            
            
            %for each folder get the size column
            
            cd (app.folderEgg)
            files1 = dir('*tiff');
            for i = 1:length(files1)
                [EggSize(i)] = UrchinSize(files1(i).name);
                MeanEgg = mean(EggSize)
            end
            
            cd (app.folderControl)
            files1 = dir('*tiff');
            for i = 1:length(files1)
                [ControlSize(i)] = UrchinSize(files1(i).name);
                MeanControl = mean(ControlSize)
            end
            
            cd (app.folderSample)
            [MeanSample,SampName] = SampSize()
          
            d = 'Egg' ;
            e = 'Control';


            f = categorical({d,e,SampName{:}}); 
      LarvaSize = [MeanEgg,MeanControl,MeanSample];
      app.UITable.Data = LarvaSize
      
      app.UITable.ColumnName = f;
      bar(app.UIAxes,f,LarvaSize)
      title(app.UIAxes,'Larva size (um)')
      
     
        end

        % Value changed function: STARTButton
        function STARTButtonValueChanged(app, event)
            value = app.STARTButton.Value;
            
          cd (app.folderSample);   
            
            load ClassFirstModel
            load ClassSecondModel


dirinfo = dir();
dirinfo(~[dirinfo.isdir]) = [];  %remove non-directories
subdirinfo = cell(length(dirinfo));
for K = 1 : length(dirinfo);
  thisdir = dirinfo(K).name;
  foldernames{K} = str2cell(thisdir);
  FractionNamestemp{K} = thisdir; 
  FractionNames = categorical(FractionNamestemp(3:end)); 
  
  subdirinfo{K} = dir(fullfile(thisdir, '*.tif'));
end
for J = 1: (length(subdirinfo)-2);
subdirinfotemp{J}  = subdirinfo{J+2,1};

pathtemp{J} = subdirinfotemp{J}.folder;
end

%
for T = 1: (length(subdirinfo)-2)
folder =  cd (char(pathtemp(T)));
[MyDataSet] = UrchinParam();

X_test = MyDataSet.data;
firstpred = plsdapred(X_test,firstmodel);

secondpred = plsdapred(X_test,secondmodel);

P=[firstpred.class_pred secondpred.class_pred];
for i=1:size(P,1)
    if P(i,1) == 2
        class_pred{i}='bad';
    else
        if P(i,2) == 1
            class_pred{i}='good';
        else
            class_pred{i}='intermediate';
        end
    end
end

Level3 = sum(P(:,1) == 2);
Level1 = sum(P(:,1) == 1 & P(:,2) == 1);
Level2 = sum(P(:,1) == 1 & P(:,2) == 2);
Levels = [Level1;Level2;Level3];
Classif(T,:) = Levels ;

app.UITable2.Data = Classif;
app.UITable2.RowName = FractionNames;
axis = categorical(FractionNames);
bar(app.UIAxes2,axis,Classif); 
title(app.UIAxes2,'Larva Classification');
legend(app.UIAxes2,categorical({'Level 1','Level 2','Level 3'}));

end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.MeasureTab = uitab(app.TabGroup);
            app.MeasureTab.Title = 'Measure';
            app.SelectEggFolderButton = uibutton(app.MeasureTab, 'push');
            app.SelectEggFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectEggFolderButtonPushed, true);
            app.SelectEggFolderButton.Text = 'Select Egg Folder';
            app.SelectControlFolderButton = uibutton(app.MeasureTab, 'push');
            app.SelectControlFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectControlFolderButtonPushed, true);
            app.SelectControlFolderButton.Text = 'Select Control Folder';
            app.SelectSampleFolderButton = uibutton(app.MeasureTab, 'push');
            app.SelectSampleFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectSampleFolderButtonPushed, true);
            app.SelectSampleFolderButton.Text = 'Select Sample Folder';
            app.MeasureButton = uibutton(app.MeasureTab, 'push');
            app.MeasureButton.ButtonPushedFcn = createCallbackFcn(app, @MeasureButtonPushed, true);
            app.MeasureButton.Text = 'Measure';
            app.UITable = uitable(app.MeasureTab);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.UIAxes = uiaxes(app.MeasureTab);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.Image = uiimage(app.MeasureTab);
            app.Image.ImageSource = 'PiElapiz.jpg';
            app.ByIkerAlvarezMoraLabel = uilabel(app.MeasureTab);
            app.ByIkerAlvarezMoraLabel.Text = 'By Iker Alvarez Mora';
            app.Image2 = uiimage(app.MeasureTab);
            app.Image2.ImageSource = 'IBEA.png';
            app.ClassificationTab = uitab(app.TabGroup);
            app.ClassificationTab.Title = 'Classification';
            app.STARTButton = uibutton(app.ClassificationTab, 'state');
            app.STARTButton.ValueChangedFcn = createCallbackFcn(app, @STARTButtonValueChanged, true);
            app.STARTButton.Text = 'START';
            app.UIAxes2 = uiaxes(app.ClassificationTab);
            title(app.UIAxes2, '')
            xlabel(app.UIAxes2, '')
            ylabel(app.UIAxes2, '')
            app.Image_2 = uiimage(app.ClassificationTab);
            app.Image_2.ImageSource = 'PiElapiz.jpg';
            app.ByIkerAlvarezMoraLabel_2 = uilabel(app.ClassificationTab);
            app.ByIkerAlvarezMoraLabel_2.Text = 'By Iker Alvarez Mora';
            app.Image2_2 = uiimage(app.ClassificationTab);
            app.Image2_2.ImageSource = 'IBEA.png';
            app.UITable2 = uitable(app.ClassificationTab);
            app.UITable2.ColumnName = {'Level 1'; 'Level 2'; 'Level 3'; ''};
            app.UITable2.RowName = {};
            app.UITable2.ColumnEditable = true;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SeaUrchinApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end